classdef PBILOptimisationApp_Team4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        STARTButton                   matlab.ui.control.Button
        CostTable                     matlab.ui.control.Table
        EpochLabel                    matlab.ui.control.Label
        Epoch                         matlab.ui.control.NumericEditField
        LearningRateEditFieldLabel    matlab.ui.control.Label
        LearningRate                  matlab.ui.control.NumericEditField
        numLoopsLabel                 matlab.ui.control.Label
        numLoops                      matlab.ui.control.NumericEditField
        Progressive_Minimum           matlab.ui.control.UIAxes
        GaugeLabel                    matlab.ui.control.Label
        Gauge                         matlab.ui.control.Gauge
        TABLELabel                    matlab.ui.control.Label
        ENTERTHEVALUESLabel           matlab.ui.control.Label
        FinalCostGauge_2Label         matlab.ui.control.Label
        FinalCostGauge                matlab.ui.control.LinearGauge
        Connections_per_concentrator  matlab.ui.control.UIAxes
        Connection_configuration      matlab.ui.control.UIAxes
        CostTableEditFieldLabel       matlab.ui.control.Label
        CostTableEditField            matlab.ui.control.EditField
        PrintOut                      matlab.ui.control.EditField
    end

    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            

%% Anonymous Function that converts bits to decimal value

costBin2Dec = @(costIndexBin) costIndexBin(1)*4 + costIndexBin(2)*2 + costIndexBin(3);


%% Customisation Parameters: 

% Number of Trials running
NumberOfTrials= app.Epoch.Value;

if NumberOfTrials == 0
    NumberOfTrials = 100;
end

% Learning Increment Rate
LearningRate=app.LearningRate.Value;

if LearningRate == 0
    LearningRate = 0.005;
end

% Number of Loops
NumberofLoops = app.numLoops.Value;
if NumberofLoops == 0
    NumberofLoops = 2000;
end
% End of Customisation Parameters


%% Array Generation:

% Probability Vector
ProbVector = ones(1,36)*0.5; % Default 0.5 Probabiity Vector
% Connection Enabled Array
ConcConnect = zeros(8,1);   % Empty array with each element representing a concentrator
%Epoch Cost Storage
EpochCost = zeros(NumberofLoops, 1);
%Cost Table
CostTable=xlsread(app.CostTableEditField.Value);
%Remove the Unnessecrary Rows and Columns From Import
CostTable=CostTable(2:9,2:13); 
app.CostTable.Data = CostTable


%% End of Array Generation


%% Main Loop
for MainLoop = 1:NumberofLoops

    Epoch = zeros(NumberOfTrials,36);
    % Create an epoch by creating an empty array.

    CostTrials = zeros(NumberOfTrials,1);
    % Create empty array to store total cost of each trial

    for EpochLoop = 1:NumberOfTrials %% HAS TO BE DONE FIRST TO FILL ARRAY. 
        Epoch(EpochLoop,:) = rand(1,36)<ProbVector;
        % Concatecates from 1 - NumTrials 1 or 0 as logic variable for this epoch. 
    end


     %Cost Calculations
    for EpochLoop = 1:NumberOfTrials
        ConcentratorConnections = zeros(8,1); % each element representing a concentrator.
        BinCost = reshape(Epoch(EpochLoop,:),3,12); %Reshapes the array to make a "binary string."

        %This Converts our Decimal Array in a Binary String and allows us to search Cost Table. 
        for NumOfTerminal = 1:12
            CostIndexDec(NumOfTerminal) = ...
            costBin2Dec(BinCost(:,NumOfTerminal));
            ConcentratorConnections(CostIndexDec(NumOfTerminal)+1) ...
            = ConcentratorConnections(CostIndexDec(NumOfTerminal)+1) + 1;
        end


        % Retrieve data for Trial Cost
        for NumOfTerminal = 1:12
            Tmp = CostTable(CostIndexDec(NumOfTerminal)+1,NumOfTerminal);
            CostTrials(EpochLoop,1) = CostTrials(EpochLoop,1) + Tmp;
        end


        % Check if trial is viable/legal trial. 
        for ConcentratorNum = 1:8
           if ConcentratorConnections(ConcentratorNum,1)>3
               CostTrials(EpochLoop,1) = 9999;
           end
        end
    end


 % This finds the minimum cost value and the assigned 36 bit index that is associated with that cost.


    [minVal,minCounter] = min(CostTrials);
    costMinDisplay(MainLoop) = minVal;

    % Creates Array minEpoch and does the calculation in order to find minimum Epoch
    minEpoch = Epoch(minCounter,:);
    for i = 1:36
        if minEpoch(i) > 0
            ProbVector(1,i) = ProbVector(1,i) + LearningRate;
            if ProbVector(1,i) > 1
                ProbVector(1,i) = 1;
            end
        else
            ProbVector(1,i) = ProbVector(1,i) - LearningRate;
            if ProbVector(1,i) < 0
                ProbVector(1,i) = 0;
            end
        end
   end

    
% End of Functionality

end

%Data Visualisation

%% Plotting results
plot(app.Progressive_Minimum, costMinDisplay)
barh(app.Connections_per_concentrator,ConcentratorConnections)
bar(app.Connection_configuration, CostIndexDec)
app.Gauge.Value = minVal;
app.FinalCostGauge.Value = minVal;

app.PrintOut.Value = int2str(minVal);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.CostTable = uitable(app.UIFigure);
            app.CostTable.ColumnName = {'Terminal1'; 'Terminal2'; 'Terminal3'; 'Terminal4'; 'Terminal5'; 'Terminal6'; 'Terminal7'; 'Terminal8'; 'Terminal9'; 'Terminal10'; 'Terminal11'; 'Terminal12'; ''};
            app.CostTable.RowName = {};
            app.EpochLabel = uilabel(app.UIFigure);
            app.EpochLabel.Text = 'Epoch';
            app.Epoch = uieditfield(app.UIFigure, 'numeric');
            app.LearningRateEditFieldLabel = uilabel(app.UIFigure);
            app.LearningRateEditFieldLabel.Text = 'Learning Rate';
            app.LearningRate = uieditfield(app.UIFigure, 'numeric');
            app.numLoopsLabel = uilabel(app.UIFigure);
            app.numLoopsLabel.Text = 'numLoops';
            app.numLoops = uieditfield(app.UIFigure, 'numeric');
            app.Progressive_Minimum = uiaxes(app.UIFigure);
            title(app.Progressive_Minimum, 'Progressive Minimum')
            xlabel(app.Progressive_Minimum, 'Number of Loops')
            ylabel(app.Progressive_Minimum, 'Minimum Cost')
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge.Limits = [100 200];
            app.TABLELabel = uilabel(app.UIFigure);
            app.TABLELabel.Text = 'TABLE:';
            app.ENTERTHEVALUESLabel = uilabel(app.UIFigure);
            app.ENTERTHEVALUESLabel.Text = 'ENTER THE VALUES';
            app.FinalCostGauge_2Label = uilabel(app.UIFigure);
            app.FinalCostGauge_2Label.Text = 'Final Cost';
            app.FinalCostGauge = uigauge(app.UIFigure, 'linear');
            app.FinalCostGauge.Limits = [100 200];
            app.Connections_per_concentrator = uiaxes(app.UIFigure);
            title(app.Connections_per_concentrator, 'Connections per concentrator')
            xlabel(app.Connections_per_concentrator, 'Connections')
            ylabel(app.Connections_per_concentrator, 'Concentrator')
            app.Connection_configuration = uiaxes(app.UIFigure);
            title(app.Connection_configuration, 'Connection Configuration')
            xlabel(app.Connection_configuration, 'Terminal')
            ylabel(app.Connection_configuration, 'Concentrator')
            app.Connection_configuration.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Connection_configuration.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'};
            app.Connection_configuration.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'};
            app.CostTableEditFieldLabel = uilabel(app.UIFigure);
            app.CostTableEditFieldLabel.Text = 'CostTable';
            app.CostTableEditField = uieditfield(app.UIFigure, 'text');
            app.CostTableEditField.Value = 'CostTable.xlsx';
            app.PrintOut = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = PBILOptimisationApp_Team4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end